classdef proj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        VideoFeedSwitchLabel  matlab.ui.control.Label
        VideoFeedSwitch       matlab.ui.control.Switch
        FixImageButton        matlab.ui.control.Button
        LockedLabel           matlab.ui.control.Label
        VerifyButton          matlab.ui.control.Button
        LampLabel             matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        FaceIDLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        breakflag % Description
        img % Description
    end


    methods (Access = private)

        % Value changed function: VideoFeedSwitch
        function VideoFeedSwitchValueChanged(app, event)
            value = app.VideoFeedSwitch.Value;
            if value == 'On'
                cam = webcam(1);
                app.UIAxes.Visible = 'on';
                while true
                    app.img = snapshot(cam);
                    imshow(app.img,'parent',app.UIAxes);
                    if app.breakflag == 1
                        clear cam
                        break
                    end
                end
            end            
        end

        % Button pushed function: FixImageButton
        function FixImageButtonPushed(app, event)
            app.breakflag = 1;
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
              detect = vision.CascadeObjectDetector();
              bbox = step(detect,app.img);
              if isempty(bbox)
                  app.LockedLabel.Text = 'No Face Detected';
              else
                  dispimg = imcrop(app.img,bbox);
                  dispimg = imresize(dispimg, [92,112]);
                  imshow(dispimg,'parent',app.UIAxes);
                  dispimg = rgb2gray(dispimg);
                  dispimg = imbinarize(dispimg);
                  load('classifier.mat');
                  classifier = fitcecoc(trainingFeatures, trainingLabels);
                  dispimgF = extractHOGFeatures(dispimg);
                  pred = predict(classifier,dispimgF);
                  if pred == 1
                    app.LockedLabel.Text = 'Unlock';
                    app.Lamp.Color = [0 1 0];
                  else
                    app.LockedLabel.Text = 'No Match';
                  end
              end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.VideoFeedSwitchLabel = uilabel(app.UIFigure);
            app.VideoFeedSwitchLabel.Text = 'VideoFeed';
            app.VideoFeedSwitch = uiswitch(app.UIFigure, 'slider');
            app.VideoFeedSwitch.ValueChangedFcn = createCallbackFcn(app, @VideoFeedSwitchValueChanged, true);
            app.FixImageButton = uibutton(app.UIFigure, 'push');
            app.FixImageButton.ButtonPushedFcn = createCallbackFcn(app, @FixImageButtonPushed, true);
            app.FixImageButton.Text = 'Fix Image';
            app.LockedLabel = uilabel(app.UIFigure);
            app.LockedLabel.Text = 'Locked';
            app.VerifyButton = uibutton(app.UIFigure, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.FaceIDLabel = uilabel(app.UIFigure);
            app.FaceIDLabel.Text = 'Face ID';
        end
    end

    methods (Access = public)

        % Construct app
        function app = proj

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end